classdef Registration_Nuance_HE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RUNButton                  matlab.ui.control.Button
        RegistrationDropDown       matlab.ui.control.DropDown
        RegistrationDropDownLabel  matlab.ui.control.Label
        ImportPanel                matlab.ui.container.Panel
        AADEditField               matlab.ui.control.EditField
        AADEditFieldLabel          matlab.ui.control.Label
        HEEditField                matlab.ui.control.EditField
        HEEditFieldLabel           matlab.ui.control.Label
        LOADButton_2               matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            switch app.RegistrationDropDown.Value
                case 'Auto-quickly'
                   strGui2 = ('main_gui_App.mlapp'); %Set your GUI file's location
                case 'Auto-accurately'
                   strGui2 = ('main_gui_App.mlapp'); %Set your GUI file's location
                case 'Manually'
                   strGui2 = ('main_gui_App.mlapp'); %Set your GUI file's location
                case 'i already have tform'
                    strGui2 = ('main_gui_App.mlapp'); %Set your GUI file's location
            end
            open (strGui2); %Open the new GUI
            closereq;       %Close the actual GUI
        end

        % Button pushed function: LOADButton_2
        function LOADButton_2Pushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % hObject    handle to LOAD_putshbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global image
            [filename1 filepath]=uigetfile('*.*','please choose a 7AAD image');%filename?????filepath?????
            image{1}=imread(strcat(filepath,filename1));
            str1=[filepath filename1];      %????????
            app.AADEditField.Value = str1;
%             set( handles.edit1,'String',str1);   % ??????????????
            [filename2 filepath]=uigetfile('*.*','please choose a H&E image');%filename?????filepath?????
            image{2}=imread(strcat(filepath,filename2));
            str2=[filepath filename2];
            app.HEEditField.Value = str2;
%             set( handles.edit2,'String',str2);
        end

        % Value changed function: RegistrationDropDown
        function RegistrationDropDownValueChanged(app, event)
            value = app.RegistrationDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportPanel = uipanel(app.UIFigure);
            app.ImportPanel.Title = 'Import';
            app.LOADButton_2 = uibutton(app.ImportPanel, 'push');
            app.LOADButton_2.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_2Pushed, true);
            app.LOADButton_2.Text = 'LOAD';
            app.HEEditFieldLabel = uilabel(app.ImportPanel);
            app.HEEditFieldLabel.Text = 'H&E';
            app.HEEditField = uieditfield(app.ImportPanel, 'text');
            app.AADEditFieldLabel = uilabel(app.ImportPanel);
            app.AADEditFieldLabel.Text = '7AAD';
            app.AADEditField = uieditfield(app.ImportPanel, 'text');
            app.RegistrationDropDownLabel = uilabel(app.UIFigure);
            app.RegistrationDropDownLabel.Text = 'Registration';
            app.RegistrationDropDown = uidropdown(app.UIFigure);
            app.RegistrationDropDown.Items = {'Auto-quickly', 'Auto-accurately', 'Manually', 'i already have tform'};
            app.RegistrationDropDown.ValueChangedFcn = createCallbackFcn(app, @RegistrationDropDownValueChanged, true);
            app.RegistrationDropDown.Value = 'i already have tform';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Registration_Nuance_HE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end